classdef Principal_Stress_popup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectPrincipalStressesPanel  matlab.ui.container.Panel
        PlotButton                    matlab.ui.control.Button
        SvPanel                       matlab.ui.container.Panel
        DensityButton                 matlab.ui.control.Button
        LithostaticGradientButton     matlab.ui.control.Button
        LoadGradientEditFieldLabel    matlab.ui.control.Label
        LoadGradientEditField         matlab.ui.control.EditField
        PorePressurePanel             matlab.ui.container.Panel
        HydrostaticButton             matlab.ui.control.Button
        SonicLogButton                matlab.ui.control.Button
        ResisitvityButton             matlab.ui.control.Button
        RFTDSTButton                  matlab.ui.control.Button
        LoadRFTDSTDataEditFieldLabel  matlab.ui.control.Label
        LoadRFTDSTDataEditField       matlab.ui.control.EditField
        FittingtypeDropDownLabel      matlab.ui.control.Label
        FittingtypeDropDown           matlab.ui.control.DropDown
        WindowLengthEditFieldLabel    matlab.ui.control.Label
        WindowLengthEditField         matlab.ui.control.NumericEditField
        ShminPanel                    matlab.ui.container.Panel
        CalculatePoissonsratioButton  matlab.ui.control.Button
        LeakoffTestDataButton         matlab.ui.control.Button
        LoadDataEditFieldLabel        matlab.ui.control.Label
        LoadDataEditField             matlab.ui.control.EditField
        FittingtypeDropDown_2Label    matlab.ui.control.Label
        FittingtypeDropDown_2         matlab.ui.control.DropDown
        WindowLengthEditField_2Label  matlab.ui.control.Label
        WindowLengthEditField_2       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
      logstressdata % Description
    end
    
    properties (Access = private)
      StressaroundWellBoreButtonb % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DensityButton
        function DensityButtonPushed(app, event)
             g=9.8;
             l_den=length(app.logstressdata.Density);
             lith_den=2700;
             app.logstressdata.Sv(1,1)=lith_den*g*app.logstressdata.Depth(1,1);

           for i=2:l_den
             app.logstressdata.Sv(i,1)=app.logstressdata.Sv(i-1,1)+app.logstressdata.Density(i,1)*g*(app.logstressdata.Depth(i,1)-app.logstressdata.Depth(i-1,1));
           end
        end

        % Button pushed function: HydrostaticButton
        function HydrostaticButtonPushed(app, event)
            app.logstressdata.PP=0.433*3.28084*app.logstressdata.Depth*10^6/145;
        end

        % Button pushed function: CalculatePoissonsratioButton
        function CalculatePoissonsratioButtonPushed(app, event)
L=length(app.logstressdata.VP);
for i=1:L
    app.logstressdata.v(i,1)=((0.5*(app.logstressdata.VP(i,1)/app.logstressdata.VS(i,1))^2)-1)/((app.logstressdata.VP(i,1)/app.logstressdata.VS(i,1))^2-1);
    app.logstressdata.G(i,1)=app.logstressdata.Density(1,1)*(app.logstressdata.VS(i,1))^2;
    app.logstressdata.E(i,1)=2*app.logstressdata.G(i,1)*(1+ app.logstressdata.v(i,1));
end 
win_len = app.WindowLengthEditField_2.Value
type=app.FittingtypeDropDown_2.Value
app.logstressdata.Shmin=app.logstressdata.PP+(app.logstressdata.Sv-app.logstressdata.PP).*(app.logstressdata.v)./(1-app.logstressdata.v);%in case leak off test is not known...can be validated using LOT
app.logstressdata.Shmin=smoothdata(app.logstressdata.Shmin,type,win_len);

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            app.StressaroundWellBoreButtonb.logkirsch=app.logstressdata
            figure
            plot(app.logstressdata.Sv,-app.logstressdata.Depth, ...
               app.logstressdata.Shmin,-app.logstressdata.Depth,...
               app.logstressdata.PP,-app.logstressdata.Depth);
           
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectPrincipalStressesPanel = uipanel(app.UIFigure);
            app.SelectPrincipalStressesPanel.Title = 'Select Principal Stresses';
            app.PlotButton = uibutton(app.SelectPrincipalStressesPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SvPanel = uipanel(app.SelectPrincipalStressesPanel);
            app.SvPanel.Title = 'Sv';
            app.DensityButton = uibutton(app.SvPanel, 'push');
            app.DensityButton.ButtonPushedFcn = createCallbackFcn(app, @DensityButtonPushed, true);
            app.DensityButton.Text = 'Density';
            app.LithostaticGradientButton = uibutton(app.SvPanel, 'push');
            app.LithostaticGradientButton.Text = 'Lithostatic Gradient';
            app.LoadGradientEditFieldLabel = uilabel(app.SvPanel);
            app.LoadGradientEditFieldLabel.Text = 'Load Gradient';
            app.LoadGradientEditField = uieditfield(app.SvPanel, 'text');
            app.PorePressurePanel = uipanel(app.SelectPrincipalStressesPanel);
            app.PorePressurePanel.Title = 'Pore Pressure';
            app.HydrostaticButton = uibutton(app.PorePressurePanel, 'push');
            app.HydrostaticButton.ButtonPushedFcn = createCallbackFcn(app, @HydrostaticButtonPushed, true);
            app.HydrostaticButton.Text = 'Hydrostatic ';
            app.SonicLogButton = uibutton(app.PorePressurePanel, 'push');
            app.SonicLogButton.Text = 'Sonic Log';
            app.ResisitvityButton = uibutton(app.PorePressurePanel, 'push');
            app.ResisitvityButton.Text = 'Resisitvity';
            app.RFTDSTButton = uibutton(app.PorePressurePanel, 'push');
            app.RFTDSTButton.Text = 'RFT/DST';
            app.LoadRFTDSTDataEditFieldLabel = uilabel(app.PorePressurePanel);
            app.LoadRFTDSTDataEditFieldLabel.Text = 'Load RFT/DST Data';
            app.LoadRFTDSTDataEditField = uieditfield(app.PorePressurePanel, 'text');
            app.FittingtypeDropDownLabel = uilabel(app.PorePressurePanel);
            app.FittingtypeDropDownLabel.Text = 'Fitting type';
            app.FittingtypeDropDown = uidropdown(app.PorePressurePanel);
            app.FittingtypeDropDown.Items = {'movmean', 'gaussian'};
            app.FittingtypeDropDown.Value = 'movmean';
            app.WindowLengthEditFieldLabel = uilabel(app.PorePressurePanel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.PorePressurePanel, 'numeric');
            app.WindowLengthEditField.Value = 20;
            app.ShminPanel = uipanel(app.SelectPrincipalStressesPanel);
            app.ShminPanel.Title = 'Shmin';
            app.CalculatePoissonsratioButton = uibutton(app.ShminPanel, 'push');
            app.CalculatePoissonsratioButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePoissonsratioButtonPushed, true);
            app.CalculatePoissonsratioButton.Text = 'Calculate Poisson''s ratio';
            app.LeakoffTestDataButton = uibutton(app.ShminPanel, 'push');
            app.LeakoffTestDataButton.Text = 'Leak off Test Data';
            app.LoadDataEditFieldLabel = uilabel(app.ShminPanel);
            app.LoadDataEditFieldLabel.Text = 'Load Data';
            app.LoadDataEditField = uieditfield(app.ShminPanel, 'text');
            app.FittingtypeDropDown_2Label = uilabel(app.ShminPanel);
            app.FittingtypeDropDown_2Label.Text = 'Fitting type';
            app.FittingtypeDropDown_2 = uidropdown(app.ShminPanel);
            app.FittingtypeDropDown_2.Items = {'movmean', 'gaussian'};
            app.FittingtypeDropDown_2.Value = 'movmean';
            app.WindowLengthEditField_2Label = uilabel(app.ShminPanel);
            app.WindowLengthEditField_2Label.Text = 'Window Length';
            app.WindowLengthEditField_2 = uieditfield(app.ShminPanel, 'numeric');
            app.WindowLengthEditField_2.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Principal_Stress_popup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end