classdef SV_calc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotButton                  matlab.ui.control.Button
        ImportDataPanel             matlab.ui.container.Panel
        FittingTypeDropDownLabel    matlab.ui.control.Label
        FittingTypeDropDown         matlab.ui.control.DropDown
        WindowlengthEditFieldLabel  matlab.ui.control.Label
        WindowlengthEditField       matlab.ui.control.EditField
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        log=struct() % Description
        
    end
    
    methods (Access = public)
        
        function data_loader(app,file_loc)
            data=xlsread(file_loc);
           
            
            app.log.Density=data(:,2)*1000;
            l_den=length(app.log.Density);
            app.log.Sv=zeros(l_den,1);
            app.log.Depth=0.3048*data(:,1);
            app.log.VP=3.048*10^5./data(:,3);
            app.log.VS=3.048*10^5./data(:,4);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function Plot_SV(app, event)
                        
            file_location=app.FilelocationEditField.Value
            data_loader(app,file_location)
           
              g=9.8;
             l_den=length(app.log.Density);
             lith_den=2700;
             app.log.Sv(1,1)=lith_den*g*app.log.Depth(1,1);

           for i=2:l_den
             app.log.Sv(i,1)=app.log.Sv(i-1,1)+app.log.Density(i,1)*g*(app.log.Depth(i,1)-app.log.Depth(i-1,1));
           end
           %calculating the poisson's ratio and Elasicity coeffiecent in case we have
%travel time data of Vs and Vp
L=length(app.log.VP);
for i=1:L
    app.log.v(i,1)=((0.5*(app.log.VP(i,1)/app.log.VS(i,1))^2)-1)/((app.log.VP(i,1)/app.log.VS(i,1))^2-1);
    app.log.G(i,1)=app.log.Density(1,1)*(app.log.VS(i,1))^2;
    app.log.E(i,1)=2*app.log.G(i,1)*(1+ app.log.v(i,1));
end 
win_len = str2num(app.WindowlengthEditField.Value)
type=app.FittingTypeDropDown.Value
app.log.PP=0.433*3.28084*app.log.Depth*10^6/145;
app.log.Shmin=app.log.PP+(app.log.Sv-app.log.PP).*(app.log.v)./(1-app.log.v);%in case leak off test is not known...can be validated using LOT
app.log.Shmin=smoothdata(app.log.Shmin,type,win_len);


plot(app.UIAxes, app.log.Sv,-app.log.Depth, ...
               app.log.Shmin,-app.log.Depth,...
               app.log.PP,-app.log.Depth);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_SV, true);
            app.PlotButton.Text = 'Plot';
            app.ImportDataPanel = uipanel(app.UIFigure);
            app.ImportDataPanel.Title = 'Import Data';
            app.FittingTypeDropDownLabel = uilabel(app.ImportDataPanel);
            app.FittingTypeDropDownLabel.Text = 'Fitting Type';
            app.FittingTypeDropDown = uidropdown(app.ImportDataPanel);
            app.FittingTypeDropDown.Items = {'movmean', 'gaussian'};
            app.FittingTypeDropDown.Value = 'movmean';
            app.WindowlengthEditFieldLabel = uilabel(app.ImportDataPanel);
            app.WindowlengthEditFieldLabel.Text = 'Window length';
            app.WindowlengthEditField = uieditfield(app.ImportDataPanel, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SV vs Depth')
            xlabel(app.UIAxes, 'SV (units)')
            ylabel(app.UIAxes, 'Depth(m)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SV_calc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end