classdef StressesAroundWellBore < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DepthEditFieldLabel             matlab.ui.control.Label
        DepthEditField                  matlab.ui.control.NumericEditField
        WellboreRadiusEditFieldLabel    matlab.ui.control.Label
        WellboreRadiusEditField         matlab.ui.control.NumericEditField
        DiscretisationvalueEditFieldLabel  matlab.ui.control.Label
        DiscretisationvalueEditField    matlab.ui.control.NumericEditField
        StressesAroundWellBoreLabel     matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        DistancefromwellEditFieldLabel  matlab.ui.control.Label
        DistancefromwellEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        logkirsch % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            
            k=find(app.logkirsch.Depth==app.DepthEditField.Value);
            rmax=app.DistancefromwellEditField.Value;
            d=app.DiscretisationvalueEditField.Value;
            R=app.WellboreRadiusEditField.Value;
            r=R+d:d:rmax
            
            %Shmax=logkirsch.Shmax(k);
            Shmax=75
            Shmin=logkirsch.Shmin(k);
            Po=logkirsch.PP(k);
            Sv=logkirsch.Sv(k);
            v=((0.5*(logkirsch.VP(k,1)/logkirsch.VS(k,1))^2)-1)/((logkirsch.VP(k,1)/logkirsch.VS(k,1))^2-1);
            theta=0:(2*pi)/360:2*pi;
            i=length(r);
            j=length(theta);
            
            for k=1:i
                for m=1:j
                    plot_r(k,m)=r(k)*cos(theta(m));
                    plot_theta(k,m)=r(k)*sin(theta(m));
            
            sigma_rr(k,m)=(1/2)*(Shmax+Shmin-2*PP)*(1-(R/r(k))^2)+(1/2)*(Shmax-Shmin)*(1-(4*(R/r(k))^2)+(3*(R/r(k))^4))*cos(2*theta(m))+PP*((R/r(k))^2);
            sigma_theta(k,m)=(1/2)*(Shmax+Shmin-2*PP)*(1+(R/r(k))^2)-(1/2)*(Shmax-Shmin)*(1+(3*(R/r(k))^4))*cos(2*theta(m))-PP*((R/r(k))^2);
            tau_r_theta(k,m)=(1/2)*(Shmax-Shmin)*(1+(2*(R/r(k))^2)-(3*(R/r(k))^4))*sin(2*theta(m));
            sigma_zz(k,m)=Sv-2*v*(Shmax-Shmin)*cos(2*theta(m))-PP;
                end
            end
            figure 
            subplot(2,2,1)
            surf(plot_r,plot_theta,sigma_rr);
            subplot(2,2,2)
            surf(plot_r,plot_theta,sigma_theta);
            subplot(2,2,3)
            surf(plot_r,plot_theta,tau_r_theta);
            subplot(2,2,4)
            surf(plot_r,plot_theta,sigma_zz);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DepthEditFieldLabel = uilabel(app.UIFigure);
            app.DepthEditFieldLabel.Text = 'Depth';
            app.DepthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WellboreRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.WellboreRadiusEditFieldLabel.Text = 'Well bore Radius';
            app.WellboreRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.WellboreRadiusEditField.Value = 0.5;
            app.DiscretisationvalueEditFieldLabel = uilabel(app.UIFigure);
            app.DiscretisationvalueEditFieldLabel.Text = 'Discretisation value';
            app.DiscretisationvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiscretisationvalueEditField.Value = 0.01;
            app.StressesAroundWellBoreLabel = uilabel(app.UIFigure);
            app.StressesAroundWellBoreLabel.Text = 'Stresses Around Well Bore';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DistancefromwellEditFieldLabel = uilabel(app.UIFigure);
            app.DistancefromwellEditFieldLabel.Text = 'Distance from well';
            app.DistancefromwellEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancefromwellEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StressesAroundWellBore

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end