classdef logplotpopup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelecttheWellDataandunitsPanel  matlab.ui.container.Panel
        Plot1DropDownLabel              matlab.ui.control.Label
        Plot1DropDown                   matlab.ui.control.DropDown
        Plot2DropDownLabel              matlab.ui.control.Label
        Plot2DropDown                   matlab.ui.control.DropDown
        Plot3DropDownLabel              matlab.ui.control.Label
        Plot3DropDown                   matlab.ui.control.DropDown
        DropDown4                       matlab.ui.control.DropDown
        DropDown4_2                     matlab.ui.control.DropDown
        DropDown4_3                     matlab.ui.control.DropDown
        PLOTButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        logdata % Description
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
           
            
            a=app.Plot1DropDown.Value;
            b=app.Plot2DropDown.Value;
            c=app.Plot3DropDown.Value;
            x=app.DropDown4.Value;
            y=app.DropDown4_2.Value;
            z=app.DropDown4_3.Value;
            d='app.logdata.';
            m=strcat(d,a);
            n=strcat(d,b);
            o=strcat(d,c);
           subplot(1,3,1)
           plot(eval(m),-app.logdata.Depth) 
           subplot(1,3,2)
           plot(eval(n),-app.logdata.Depth) 
           subplot(1,3,3)
           plot(eval(o),-app.logdata.Depth)
            delete(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelecttheWellDataandunitsPanel = uipanel(app.UIFigure);
            app.SelecttheWellDataandunitsPanel.Title = 'Select the Well Data and units';
            app.Plot1DropDownLabel = uilabel(app.SelecttheWellDataandunitsPanel);
            app.Plot1DropDownLabel.Text = 'Plot 1';
            app.Plot1DropDown = uidropdown(app.SelecttheWellDataandunitsPanel);
            app.Plot1DropDown.Items = {'Density', 'Vp', 'Vs', 'Resitivity'};
            app.Plot1DropDown.Value = 'Density';
            app.Plot2DropDownLabel = uilabel(app.SelecttheWellDataandunitsPanel);
            app.Plot2DropDownLabel.Text = 'Plot 2';
            app.Plot2DropDown = uidropdown(app.SelecttheWellDataandunitsPanel);
            app.Plot2DropDown.Items = {'Density', 'VP', 'VS', 'Resitivity'};
            app.Plot2DropDown.Value = 'Density';
            app.Plot3DropDownLabel = uilabel(app.SelecttheWellDataandunitsPanel);
            app.Plot3DropDownLabel.Text = 'Plot 3';
            app.Plot3DropDown = uidropdown(app.SelecttheWellDataandunitsPanel);
            app.Plot3DropDown.Items = {'Density', 'VP', 'VS', 'Resitivity'};
            app.Plot3DropDown.Value = 'Density';
            app.DropDown4 = uidropdown(app.SelecttheWellDataandunitsPanel);
            app.DropDown4.Items = {'g/cc', 'm/s', ''};
            app.DropDown4.Value = 'g/cc';
            app.DropDown4_2 = uidropdown(app.SelecttheWellDataandunitsPanel);
            app.DropDown4_2.Items = {'g/cc', 'm/s'};
            app.DropDown4_2.Value = 'm/s';
            app.DropDown4_3 = uidropdown(app.SelecttheWellDataandunitsPanel);
            app.DropDown4_3.Items = {'g/cc', 'm/s'};
            app.DropDown4_3.Value = 'm/s';
            app.PLOTButton = uibutton(app.SelecttheWellDataandunitsPanel, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logplotpopup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end