classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewMenu                     matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        WorkflowPanel               matlab.ui.container.Panel
        StressButton                matlab.ui.control.Button
        LogPlotButton               matlab.ui.control.Button
        StressaroundWellBoreButton  matlab.ui.control.Button
        StressFieldButton           matlab.ui.control.Button
        LocationEditFieldLabel      matlab.ui.control.Label
        LocationEditField           matlab.ui.control.EditField
        LoadDataButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Stressa                   
        logplotpopupa
        StressaroundWellBoreButtona
    end
    
    properties (Access = public)
      log=struct() % Description
    end


  methods (Access = public)


           function data_loader(app,file_loc)
            data=xlsread(file_loc);
           
            
            app.log.Density=data(:,2)*1000;
            l_den=length(app.log.Density);
            app.log.Sv=zeros(l_den,1);
            app.log.Depth=0.3048*data(:,1);
            app.log.VP=3.048*10^5./data(:,3);
            app.log.VS=3.048*10^5./data(:,4);
           end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StressButton
        function StressButtonPushed(app, event)
             app.Stressa = Principal_Stress_popup;
             app.Stressa.logstressdata=app.log
        end

        % Button pushed function: LogPlotButton
        function LogPlotButtonPushed(app, event)

            app.logplotpopupa=logplotpopup;
            app.logplotpopupa.logdata=app.log
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
           data_loader(app,app.LocationEditField.Value)

        end

        % Button pushed function: StressaroundWellBoreButton
        function StressaroundWellBoreButtonPushed(app, event)
            app.StressaroundWellBoreButtona = StressesAroundWellBore;
            app.StressaroundWellBoreButtona.logkirsch=app.log
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.WorkflowPanel = uipanel(app.UIFigure);
            app.WorkflowPanel.Title = 'Workflow';
            app.StressButton = uibutton(app.WorkflowPanel, 'push');
            app.StressButton.ButtonPushedFcn = createCallbackFcn(app, @StressButtonPushed, true);
            app.StressButton.Text = 'Stress';
            app.LogPlotButton = uibutton(app.WorkflowPanel, 'push');
            app.LogPlotButton.ButtonPushedFcn = createCallbackFcn(app, @LogPlotButtonPushed, true);
            app.LogPlotButton.Text = 'Log Plot';
            app.StressaroundWellBoreButton = uibutton(app.WorkflowPanel, 'push');
            app.StressaroundWellBoreButton.ButtonPushedFcn = createCallbackFcn(app, @StressaroundWellBoreButtonPushed, true);
            app.StressaroundWellBoreButton.Text = 'Stress around Well Bore';
            app.StressFieldButton = uibutton(app.WorkflowPanel, 'push');
            app.StressFieldButton.Text = 'Stress Field';
            app.LocationEditFieldLabel = uilabel(app.UIFigure);
            app.LocationEditFieldLabel.Text = 'Location';
            app.LocationEditField = uieditfield(app.UIFigure, 'text');
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end